classdef MBDTools < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MBDApp                      matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        ModelTestTab                matlab.ui.container.Tab
        TestHarnessGenerationPanel  matlab.ui.container.Panel
        OpenModelButton             matlab.ui.control.Button
        OpenExcelFileButton         matlab.ui.control.Button
        OpenHarnessButton           matlab.ui.control.Button
        CreateTestButton            matlab.ui.control.StateButton
        UpdateTestButton            matlab.ui.control.StateButton
        ModelTextAreaLabel          matlab.ui.control.Label
        ModelTextArea               matlab.ui.control.TextArea
        TestDocTextAreaLabel        matlab.ui.control.Label
        TestDocTextArea             matlab.ui.control.TextArea
        HarnessTextAreaLabel        matlab.ui.control.Label
        HarnessTextArea             matlab.ui.control.TextArea
        NameTextAreaLabel           matlab.ui.control.Label
        NameTextArea                matlab.ui.control.TextArea
        OpenHarnessButton_2         matlab.ui.control.Button
        ButtonGroup                 matlab.ui.container.ButtonGroup
        QualificationTestButton_2   matlab.ui.control.RadioButton
        IntegrationTestButton       matlab.ui.control.RadioButton
        UnitTestButton_2            matlab.ui.control.RadioButton
        OpenModelPanel              matlab.ui.container.Panel
        UnitModelButton             matlab.ui.control.StateButton
        IntegrateModelButton        matlab.ui.control.StateButton
        OpenTestFilePanel           matlab.ui.container.Panel
        UnitTestButton              matlab.ui.control.StateButton
        IntegrateTestButton         matlab.ui.control.StateButton
        IntegrateTestButton_2       matlab.ui.control.StateButton
        ModelAdvisorPanel           matlab.ui.container.Panel
        RunButton                   matlab.ui.control.StateButton
        OpenReportButton            matlab.ui.control.StateButton
        SettingButton               matlab.ui.control.StateButton
        SWE4UnitTestPanel           matlab.ui.container.Panel
        RunButton_2                 matlab.ui.control.StateButton
        OpenReportButton_2          matlab.ui.control.StateButton
        SettingButton_2             matlab.ui.control.StateButton
        RequirementTab              matlab.ui.container.Tab
        UITable                     matlab.ui.control.Table
        ModelDesignTab              matlab.ui.container.Tab
        AutosarTab                  matlab.ui.container.Tab
        MBDLabel                    matlab.ui.control.Label
    end

    
    properties (Access = private)
        testInfo        %The Information used to create test Harness
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            uifigureOnTop(app.MBDApp);
        end

        % Button pushed function: OpenExcelFileButton
        function OpenExcelFileButtonPushed(app, event)
            [fileName, pathName] = uigetfile({'*.xlsx'},'File Selector');
            fullPath = fullfile(pathName, fileName);
            tableContent = readtable(fullPath, "Sheet",1);
            app.UITable.Data = tableContent;
            app.testInfo.Doc = fullPath;
            app.testInfo.Model = 'bbb';
            disp(app.testInfo.Doc)
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
%             selectedTab = app.TabGroup.SelectedTab;
%             app.testInfo.Doc = 'aaa'
%             app.testInfo.Model = 'bbb'
            
        end

        % Button pushed function: OpenHarnessButton
        function OpenHarnessButtonPushed(app, event)
            harnessList = sltest.harness.find(bdroot)
            f = uifigure('Name','Testharness List');
            uc = uicontrol(f, 'Style','listbox')
            uc.Position = [20 175 160 220];
            uc.String = harnessList.name
            uicontrol(uc)
            
        end

        % Button pushed function: OpenModelButton
        function OpenModelButtonPushed(app, event)
            
        end

        % Callback function
        function Button2Pushed(app, event)
            set(app.MBDApp,'WindowStyle','modal')
        end

        % Value changed function: CreateTestButton
        function CreateTestButtonValueChanged(app, event)
            value = app.CreateTestButton.Value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MBDApp = uifigure('Visible', 'off');
            app.MBDApp.Name = 'MBD Tools';
            app.TabGroup = uitabgroup(app.MBDApp);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ModelTestTab = uitab(app.TabGroup);
            app.ModelTestTab.Title = 'Model Test';
            app.TestHarnessGenerationPanel = uipanel(app.ModelTestTab);
            app.TestHarnessGenerationPanel.Title = 'Test Harness Generation';
            app.OpenModelButton = uibutton(app.TestHarnessGenerationPanel, 'push');
            app.OpenModelButton.ButtonPushedFcn = createCallbackFcn(app, @OpenModelButtonPushed, true);
            app.OpenModelButton.Text = '...';
            app.OpenExcelFileButton = uibutton(app.TestHarnessGenerationPanel, 'push');
            app.OpenExcelFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenExcelFileButtonPushed, true);
            app.OpenExcelFileButton.Text = '...';
            app.OpenHarnessButton = uibutton(app.TestHarnessGenerationPanel, 'push');
            app.OpenHarnessButton.ButtonPushedFcn = createCallbackFcn(app, @OpenHarnessButtonPushed, true);
            app.OpenHarnessButton.Text = '...';
            app.CreateTestButton = uibutton(app.TestHarnessGenerationPanel, 'state');
            app.CreateTestButton.ValueChangedFcn = createCallbackFcn(app, @CreateTestButtonValueChanged, true);
            app.CreateTestButton.Text = 'Create Test';
            app.UpdateTestButton = uibutton(app.TestHarnessGenerationPanel, 'state');
            app.UpdateTestButton.Text = 'Update Test';
            app.ModelTextAreaLabel = uilabel(app.TestHarnessGenerationPanel);
            app.ModelTextAreaLabel.Text = 'Model';
            app.ModelTextArea = uitextarea(app.TestHarnessGenerationPanel);
            app.TestDocTextAreaLabel = uilabel(app.TestHarnessGenerationPanel);
            app.TestDocTextAreaLabel.Text = 'TestDoc';
            app.TestDocTextArea = uitextarea(app.TestHarnessGenerationPanel);
            app.HarnessTextAreaLabel = uilabel(app.TestHarnessGenerationPanel);
            app.HarnessTextAreaLabel.Text = 'Harness';
            app.HarnessTextArea = uitextarea(app.TestHarnessGenerationPanel);
            app.NameTextAreaLabel = uilabel(app.TestHarnessGenerationPanel);
            app.NameTextAreaLabel.Text = 'Name';
            app.NameTextArea = uitextarea(app.TestHarnessGenerationPanel);
            app.OpenHarnessButton_2 = uibutton(app.TestHarnessGenerationPanel, 'push');
            app.OpenHarnessButton_2.Text = '...';
            app.ButtonGroup = uibuttongroup(app.TestHarnessGenerationPanel);
            app.QualificationTestButton_2 = uiradiobutton(app.ButtonGroup);
            app.QualificationTestButton_2.Text = 'Qualification Test';
            app.QualificationTestButton_2.Value = true;
            app.IntegrationTestButton = uiradiobutton(app.ButtonGroup);
            app.IntegrationTestButton.Text = 'Integration Test';
            app.UnitTestButton_2 = uiradiobutton(app.ButtonGroup);
            app.UnitTestButton_2.Text = 'Unit Test';
            app.OpenModelPanel = uipanel(app.ModelTestTab);
            app.OpenModelPanel.Title = 'Open Model';
            app.UnitModelButton = uibutton(app.OpenModelPanel, 'state');
            app.UnitModelButton.Text = 'Unit Model';
            app.IntegrateModelButton = uibutton(app.OpenModelPanel, 'state');
            app.IntegrateModelButton.Text = 'Integrate Model';
            app.OpenTestFilePanel = uipanel(app.ModelTestTab);
            app.OpenTestFilePanel.Title = 'Open Test File';
            app.UnitTestButton = uibutton(app.OpenTestFilePanel, 'state');
            app.UnitTestButton.Text = 'Unit Test';
            app.IntegrateTestButton = uibutton(app.OpenTestFilePanel, 'state');
            app.IntegrateTestButton.Text = 'Integrate Test';
            app.IntegrateTestButton_2 = uibutton(app.OpenTestFilePanel, 'state');
            app.IntegrateTestButton_2.Text = 'Integrate Test';
            app.ModelAdvisorPanel = uipanel(app.ModelTestTab);
            app.ModelAdvisorPanel.Title = 'Model Advisor';
            app.RunButton = uibutton(app.ModelAdvisorPanel, 'state');
            app.RunButton.Text = 'Run';
            app.OpenReportButton = uibutton(app.ModelAdvisorPanel, 'state');
            app.OpenReportButton.Text = 'Open Report';
            app.SettingButton = uibutton(app.ModelAdvisorPanel, 'state');
            app.SettingButton.Text = 'Setting';
            app.SWE4UnitTestPanel = uipanel(app.ModelTestTab);
            app.SWE4UnitTestPanel.Title = 'SWE.4: Unit Test';
            app.RunButton_2 = uibutton(app.SWE4UnitTestPanel, 'state');
            app.RunButton_2.Text = 'Run';
            app.OpenReportButton_2 = uibutton(app.SWE4UnitTestPanel, 'state');
            app.OpenReportButton_2.Text = 'Open Report';
            app.SettingButton_2 = uibutton(app.SWE4UnitTestPanel, 'state');
            app.SettingButton_2.Text = 'Setting';
            app.RequirementTab = uitab(app.TabGroup);
            app.RequirementTab.Title = 'Requirement';
            app.UITable = uitable(app.RequirementTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.ModelDesignTab = uitab(app.TabGroup);
            app.ModelDesignTab.Title = 'Model Design';
            app.AutosarTab = uitab(app.TabGroup);
            app.AutosarTab.Title = 'Autosar';
            app.MBDLabel = uilabel(app.MBDApp);
            app.MBDLabel.Text = 'MBD工具箱';
            % Show the figure after all components are created
            app.MBDApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MBDTools

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MBDApp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MBDApp)
        end
    end
end